classdef GUIQPSK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        CarrierNumberSliderLabel  matlab.ui.control.Label
        CarrierNumberSlider       matlab.ui.control.Slider
        ButtonGroup               matlab.ui.container.ButtonGroup
        EquivocationButton        matlab.ui.control.ToggleButton
        MutualInformationButton   matlab.ui.control.ToggleButton
        HiddenBitsButton          matlab.ui.control.ToggleButton
        HeatmapXEditFieldLabel    matlab.ui.control.Label
        HeatmapXEditField         matlab.ui.control.NumericEditField
        HeatmapYEditFieldLabel    matlab.ui.control.Label
        HeatmapYEditField         matlab.ui.control.NumericEditField
        CarrierEditField          matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel     matlab.ui.control.Label
        EpsilonEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Noise(64, 90, 345); % Linear Noise floor
        Signal(64, 90, 345); % Linear Signal
    end
    
    methods (Access = public)
        
        function plotEquivocation(app)
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            sigmaSquared = zeros(64, 90, 345);
            H2 = zeros(64, 90, 345);
            
            heatmapX = app.HeatmapXEditField.Value;
            heatmapY = app.HeatmapYEditField.Value;
            carrier = app.CarrierEditField.Value;
            
            sigmaSquared(carrier, heatmapY, heatmapX) = app.Noise(carrier, heatmapY, heatmapX)/2;
            H2(carrier,heatmapY,heatmapX) = sqrt(app.Signal(carrier,heatmapY,heatmapX));
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared(carrier, heatmapY, heatmapX)))*exp((-1/(2*sigmaSquared(carrier, heatmapY, heatmapX)))*abs((x-H2(carrier, heatmapY, heatmapX)*a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            %%%%% Plot Equivocation
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            surface(app.UIAxes, real_x, imag_x, H);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared(carrier, heatmapY, heatmapX)) ...
                + "    H= " + string(H2(carrier, heatmapY, heatmapX)));
            xlabel(app.UIAxes, 'real');
            ylabel(app.UIAxes, 'imaginary');
            zlabel(app.UIAxes, 'Equivocation');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end


        function plotMutualInformation(app)
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            sigmaSquared = zeros(64, 90, 345);
            H2 = zeros(64, 90, 345);
            
            heatmapX = app.HeatmapXEditField.Value;
            heatmapY = app.HeatmapYEditField.Value;
            carrier = app.CarrierEditField.Value;
            
            sigmaSquared(carrier, heatmapY, heatmapX) = app.Noise(carrier, heatmapY, heatmapX)/2;
            H2(carrier,heatmapY,heatmapX) = sqrt(app.Signal(carrier,heatmapY,heatmapX));
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared(carrier, heatmapY, heatmapX)))*exp((-1/(2*sigmaSquared(carrier, heatmapY, heatmapX)))*abs((x-H2(carrier, heatmapY, heatmapX)*a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            %%%%% Plot Equivocation
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            I = 2 - H;
            surface(app.UIAxes, real_x, imag_x, I);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared(carrier, heatmapY, heatmapX)) ...
                + "    H= " + string(H2(carrier, heatmapY, heatmapX)));
            xlabel(app.UIAxes, 'real');
            ylabel(app.UIAxes, 'imaginary');
            zlabel(app.UIAxes, 'Mutual Information');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end


        function plotHiddenBits(app)
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            sigmaSquared = zeros(64, 90, 345);
            H2 = zeros(64, 90, 345);
            
            heatmapX = app.HeatmapXEditField.Value;
            heatmapY = app.HeatmapYEditField.Value;
            carrier = app.CarrierEditField.Value;
            
            sigmaSquared(carrier, heatmapY, heatmapX) = app.Noise(carrier, heatmapY, heatmapX)/2;
            H2(carrier,heatmapY,heatmapX) = sqrt(app.Signal(carrier,heatmapY,heatmapX));
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared(carrier, heatmapY, heatmapX)))*exp((-1/(2*sigmaSquared(carrier, heatmapY, heatmapX)))*abs((x-H2(carrier, heatmapY, heatmapX)*a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            %%%%% Plot Equivocation
            
            I = 2 - H;
            bits = zeros(101,101);
            epsilon = app.EpsilonEditField.Value;
            
            for c = 1:101
                for d = 1:101
                    if (I(c,d) < epsilon)
                        bits(c,d) = 2;
                    else if (I(c,d) < 1+epsilon)
                            bits(c,d) = 1;
                        else
                            bits (c,d) = 0;
                        end
                    end
                end
            end
            %%%%% Bits lost
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            surface(app.UIAxes, real_x, imag_x, bits);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared(carrier, heatmapY, heatmapX)) ...
                + "    H= " + string(H2(carrier, heatmapY, heatmapX)) ...
                + "    epsilon= " + string(epsilon));
            xlabel(app.UIAxes, 'real');
            ylabel(app.UIAxes, 'imaginary');
            zlabel(app.UIAxes, 'Bits');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%% Load stuff   -> Save as a property?
            load('C:\Users\benjj\Work\EcEnResearchAssistant\HarrisonRice\Practice_Files\Kalin\QPSK\linear_noisefloor.mat');
            load('C:\Users\benjj\Work\EcEnResearchAssistant\HarrisonRice\Practice_Files\Kalin\QPSK\linear_signal.mat');
            
            app.Noise = linear_noisefloor;
            app.Signal = linear_signal;
            
            plotEquivocation(app);
            %%% Eventually, have this run everything and have the plot just select for speed
            %% Run all, save it as a property, then just access it
        end

        % Value changed function: CarrierNumberSlider
        function CarrierNumberSliderValueChanged(app, event)
            app.CarrierEditField.Value = floor(app.CarrierNumberSlider.Value);
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Callback function
        function CarrierEditField2ValueChanged(app, event)
            
        end

        % Value changed function: CarrierEditField
        function CarrierEditFieldValueChanged(app, event)
            app.CarrierNumberSlider.Value = app.CarrierEditField.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changed function: HeatmapXEditField
        function HeatmapXEditFieldValueChanged(app, event)
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changed function: HeatmapYEditField
        function HeatmapYEditFieldValueChanged(app, event)
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Callback function: ButtonGroup, EpsilonEditField
        function ButtonGroupSelectionChanged(app, event)
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changing function: CarrierNumberSlider
        function CarrierNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CarrierEditField.Value = floor(changingValue);
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CarrierNumberSliderLabel = uilabel(app.UIFigure);
            app.CarrierNumberSliderLabel.Text = 'Carrier Number';
            app.CarrierNumberSlider = uislider(app.UIFigure);
            app.CarrierNumberSlider.Limits = [1 64];
            app.CarrierNumberSlider.ValueChangedFcn = createCallbackFcn(app, @CarrierNumberSliderValueChanged, true);
            app.CarrierNumberSlider.ValueChangingFcn = createCallbackFcn(app, @CarrierNumberSliderValueChanging, true);
            app.CarrierNumberSlider.Value = 45;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EquivocationButton = uitogglebutton(app.ButtonGroup);
            app.EquivocationButton.Text = 'Equivocation';
            app.EquivocationButton.Value = true;
            app.MutualInformationButton = uitogglebutton(app.ButtonGroup);
            app.MutualInformationButton.Text = 'Mutual Information';
            app.HiddenBitsButton = uitogglebutton(app.ButtonGroup);
            app.HiddenBitsButton.Text = 'Hidden Bits';
            app.HeatmapXEditFieldLabel = uilabel(app.UIFigure);
            app.HeatmapXEditFieldLabel.Text = 'Heatmap X';
            app.HeatmapXEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatmapXEditField.Limits = [1 345];
            app.HeatmapXEditField.ValueChangedFcn = createCallbackFcn(app, @HeatmapXEditFieldValueChanged, true);
            app.HeatmapXEditField.Value = 10;
            app.HeatmapYEditFieldLabel = uilabel(app.UIFigure);
            app.HeatmapYEditFieldLabel.Text = 'Heatmap Y';
            app.HeatmapYEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatmapYEditField.Limits = [1 90];
            app.HeatmapYEditField.ValueChangedFcn = createCallbackFcn(app, @HeatmapYEditFieldValueChanged, true);
            app.HeatmapYEditField.Value = 10;
            app.CarrierEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierEditField.Limits = [1 64];
            app.CarrierEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierEditFieldValueChanged, true);
            app.CarrierEditField.Value = 45;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Limits = [1e-06 Inf];
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EpsilonEditField.Value = 0.01;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIQPSK

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end