classdef GUI_Bitwise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        SigmaSquaredEditFieldLabel  matlab.ui.control.Label
        SigmaSquaredEditField       matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel       matlab.ui.control.Label
        EpsilonEditField            matlab.ui.control.NumericEditField
        SigmaSquaredSliderLabel     matlab.ui.control.Label
        SigmaSquaredSlider          matlab.ui.control.Slider
        EpsilonSliderLabel          matlab.ui.control.Label
        EpsilonSlider               matlab.ui.control.Slider
        ButtonGroup                 matlab.ui.container.ButtonGroup
        EquivocationButton          matlab.ui.control.ToggleButton
        MutualInformationButton     matlab.ui.control.ToggleButton
        BitsButton                  matlab.ui.control.ToggleButton
        PlotCombinedButton          matlab.ui.control.StateButton
        ToggleRotateSwitchLabel     matlab.ui.control.Label
        ToggleRotateSwitch          matlab.ui.control.ToggleSwitch
        EditField                   matlab.ui.control.EditField
    end

    
    methods (Access = public)
        
        function plotEquivocation(app)
            
            sigmaSquared = app.SigmaSquaredEditField.Value;
            G = 1;          % H in the likelihood equation, but we are using H for equivocation
            epsilon = app.EpsilonEditField.Value;
            b = [1 -1];     % Real bit
            c = [j -j];     % Imaginary bit
            
            %%%%%%%%%%%%%%%%%%%
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            %% Equivocation
            % Likelihood function for the real bit
            fx_real = zeros(2, 101, 101);
            fxSum_real = zeros(1, 101, 101);
            for loop = 1:2
                fx_real(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*b(loop)).^2));
                fxSum_real(1, :,:) = fxSum_real(1, :,:) + fx_real(loop,:,:);
            end
            fxSum_real = fxSum_real .* .5;
            
            % Likelihood function for the imaginary bit
            fx_imag = zeros(2, 101, 101);
            fxSum_imag = zeros(1, 101, 101);
            for loop = 1:2
                fx_imag(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*c(loop)).^2));
                fxSum_imag(1, :,:) = fxSum_imag(1, :,:) + fx_imag(loop,:,:);
            end
            fxSum_imag = fxSum_imag .* .5;
            
            % Probability for real bit
            p_b= zeros(2, 101, 101);
            for n = 1:2
                p_b(n,:,:) = fx_real(n,:,:) ./ (2*fxSum_real(1,:,:));
            end
            
            % Probability for imaginary bit
            p_c= zeros(2, 101, 101);
            for n = 1:2
                p_c(n,:,:) = fx_imag(n,:,:) ./ (2*fxSum_imag(1,:,:));
            end
            
            % Equivocation for real part
            H_real = zeros(101, 101);
            sumOfProb_real = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_real(xCoord+51,yCoord+51) = sumOfProb_real(xCoord+51,yCoord+51) + ...
                            p_b(loop,xCoord+51,yCoord+51)*log2(p_b(loop,xCoord+51,yCoord+51));
                    end
                    H_real((xCoord+51), (yCoord+51)) = -(sumOfProb_real(xCoord+51, yCoord+51));
                end
            end
            
            % Equivocation for imaginary part
            H_imag = zeros(101, 101);
            sumOfProb_imag = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_imag(xCoord+51,yCoord+51) = sumOfProb_imag(xCoord+51,yCoord+51) + ...
                            p_c(loop,xCoord+51,yCoord+51)*log2(p_c(loop,xCoord+51,yCoord+51));
                    end
                    H_imag((xCoord+51), (yCoord+51)) = -(sumOfProb_imag(xCoord+51, yCoord+51));
                end
            end
            
            % Combined Equivocation
            H = H_real + H_imag;
            
            
            %%%%% Plot Equivocation
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            if (app.PlotCombinedButton.Value)
                surface(app.UIAxes, real_x, imag_x, H);
            else
                surface(app.UIAxes, real_x, imag_x, H_real);
                surface(app.UIAxes, real_x, imag_x, H_imag);
            end
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'H(X|z)');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end
        
        
        function plotInformation(app)
            
            sigmaSquared = app.SigmaSquaredEditField.Value;
            G = 1;          % H in the likelihood equation, but we are using H for equivocation
            epsilon = app.EpsilonEditField.Value;
            b = [1 -1];     % Real bit
            c = [j -j];     % Imaginary bit
            
            %%%%%%%%%%%%%%%%%%%
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            %% Equivocation
            % Likelihood function for the real bit
            fx_real = zeros(2, 101, 101);
            fxSum_real = zeros(1, 101, 101);
            for loop = 1:2
                fx_real(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*b(loop)).^2));
                fxSum_real(1, :,:) = fxSum_real(1, :,:) + fx_real(loop,:,:);
            end
            fxSum_real = fxSum_real .* .5;
            
            % Likelihood function for the imaginary bit
            fx_imag = zeros(2, 101, 101);
            fxSum_imag = zeros(1, 101, 101);
            for loop = 1:2
                fx_imag(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*c(loop)).^2));
                fxSum_imag(1, :,:) = fxSum_imag(1, :,:) + fx_imag(loop,:,:);
            end
            fxSum_imag = fxSum_imag .* .5;
            
            % Probability for real bit
            p_b= zeros(2, 101, 101);
            for n = 1:2
                p_b(n,:,:) = fx_real(n,:,:) ./ (2*fxSum_real(1,:,:));
            end
            
            % Probability for imaginary bit
            p_c= zeros(2, 101, 101);
            for n = 1:2
                p_c(n,:,:) = fx_imag(n,:,:) ./ (2*fxSum_imag(1,:,:));
            end
            
            % Equivocation for real part
            H_real = zeros(101, 101);
            sumOfProb_real = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_real(xCoord+51,yCoord+51) = sumOfProb_real(xCoord+51,yCoord+51) + ...
                            p_b(loop,xCoord+51,yCoord+51)*log2(p_b(loop,xCoord+51,yCoord+51));
                    end
                    H_real((xCoord+51), (yCoord+51)) = -(sumOfProb_real(xCoord+51, yCoord+51));
                end
            end
            
            % Equivocation for imaginary part
            H_imag = zeros(101, 101);
            sumOfProb_imag = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_imag(xCoord+51,yCoord+51) = sumOfProb_imag(xCoord+51,yCoord+51) + ...
                            p_c(loop,xCoord+51,yCoord+51)*log2(p_c(loop,xCoord+51,yCoord+51));
                    end
                    H_imag((xCoord+51), (yCoord+51)) = -(sumOfProb_imag(xCoord+51, yCoord+51));
                end
            end
            
            % Combined Equivocation
            H = H_real + H_imag;
            
            
            %% Mutual Information
            
            I_real = 1 - H_real;
            I_imag = 1 - H_imag;
            
            I = I_real + I_imag;
            
            %%%%% Plot Mutual Information
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            if (app.PlotCombinedButton.Value)
                surface(app.UIAxes, real_x, imag_x, I);
            else
                surface(app.UIAxes, real_x, imag_x, I_real);
                surface(app.UIAxes, real_x, imag_x, I_imag);
            end
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'I(Z^n;X^n');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end


        function plotBits(app)
            sigmaSquared = app.SigmaSquaredEditField.Value;
            G = 1;          % H in the likelihood equation, but we are using H for equivocation
            epsilon = app.EpsilonEditField.Value;
            b = [1 -1];     % Real bit
            c = [j -j];     % Imaginary bit
            
            %%%%%%%%%%%%%%%%%%%
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            %% Equivocation
            % Likelihood function for the real bit
            fx_real = zeros(2, 101, 101);
            fxSum_real = zeros(1, 101, 101);
            for loop = 1:2
                fx_real(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*b(loop)).^2));
                fxSum_real(1, :,:) = fxSum_real(1, :,:) + fx_real(loop,:,:);
            end
            fxSum_real = fxSum_real .* .5;
            
            % Likelihood function for the imaginary bit
            fx_imag = zeros(2, 101, 101);
            fxSum_imag = zeros(1, 101, 101);
            for loop = 1:2
                fx_imag(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-1*c(loop)).^2));
                fxSum_imag(1, :,:) = fxSum_imag(1, :,:) + fx_imag(loop,:,:);
            end
            fxSum_imag = fxSum_imag .* .5;
            
            % Probability for real bit
            p_b= zeros(2, 101, 101);
            for n = 1:2
                p_b(n,:,:) = fx_real(n,:,:) ./ (2*fxSum_real(1,:,:));
            end
            
            % Probability for imaginary bit
            p_c= zeros(2, 101, 101);
            for n = 1:2
                p_c(n,:,:) = fx_imag(n,:,:) ./ (2*fxSum_imag(1,:,:));
            end
            
            % Equivocation for real part
            H_real = zeros(101, 101);
            sumOfProb_real = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_real(xCoord+51,yCoord+51) = sumOfProb_real(xCoord+51,yCoord+51) + ...
                            p_b(loop,xCoord+51,yCoord+51)*log2(p_b(loop,xCoord+51,yCoord+51));
                    end
                    H_real((xCoord+51), (yCoord+51)) = -(sumOfProb_real(xCoord+51, yCoord+51));
                end
            end
            
            % Equivocation for imaginary part
            H_imag = zeros(101, 101);
            sumOfProb_imag = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:2
                        sumOfProb_imag(xCoord+51,yCoord+51) = sumOfProb_imag(xCoord+51,yCoord+51) + ...
                            p_c(loop,xCoord+51,yCoord+51)*log2(p_c(loop,xCoord+51,yCoord+51));
                    end
                    H_imag((xCoord+51), (yCoord+51)) = -(sumOfProb_imag(xCoord+51, yCoord+51));
                end
            end
            
            % Combined Equivocation
            H = H_real + H_imag;
            
            
            %% Mutual Information
            
            I_real = 1 - H_real;
            I_imag = 1 - H_imag;
            
            I = I_real + I_imag;
            
            %% Bits
            
            bits_real = zeros(101,101);
            for c = 1:101
                for d = 1:101
                    if (I_real(c,d) < epsilon)  % Compares to a threshold
                        bits_real(c,d) = 1;
                    else
                        bits_real (c,d) = 0;
                    end
                end
            end
            
            bits_imag = zeros(101,101);
            for c = 1:101
                for d = 1:101
                    if (I_imag(c,d) < epsilon)
                        bits_imag(c,d) = 1;
                    else
                        bits_imag (c,d) = 0;
                    end
                end
            end
            
            bits = bits_imag + bits_real;
            
            %%%%% Bits lost
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            if (app.PlotCombinedButton.Value)
                surface(app.UIAxes, real_x, imag_x, bits);
            else
                surface(app.UIAxes, real_x, imag_x, bits_real);
                surface(app.UIAxes, real_x, imag_x, bits_imag);
            end
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'Bits');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotEquivocation(app);
        end

        % Value changed function: EpsilonSlider
        function EpsilonSliderValueChanged(app, event)
            value = app.EpsilonSlider.Value;
            app.EpsilonEditField.Value = value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Value changed function: SigmaSquaredEditField
        function SigmaSquaredEditFieldValueChanged(app, event)
            value = app.SigmaSquaredEditField.Value;
            app.SigmaSquaredSlider.Value = value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            value = app.EpsilonEditField.Value;
            app.EpsilonSlider.Value = value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Value changed function: SigmaSquaredSlider
        function SigmaSquaredSliderValueChanged(app, event)
            value = app.SigmaSquaredSlider.Value;
            app.SigmaSquaredEditField.Value = value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Value changed function: PlotCombinedButton
        function PlotCombinedButtonValueChanged(app, event)
            value = app.PlotCombinedButton.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotInformation(app);
                case app.BitsButton
                    plotBits(app);
            end
        end

        % Value changed function: ToggleRotateSwitch
        function ToggleRotateSwitchValueChanged(app, event)
            value = app.ToggleRotateSwitch.Value;           
            if (value(1:2) == 'On')
                rotate3d(app.UIAxes,'on');
            else
                view(app.UIAxes, 45, 60);
                rotate3d(app.UIAxes,'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SigmaSquaredEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaSquaredEditFieldLabel.Text = 'Sigma Squared';
            app.SigmaSquaredEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaSquaredEditField.Limits = [1e-05 5];
            app.SigmaSquaredEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaSquaredEditFieldValueChanged, true);
            app.SigmaSquaredEditField.Value = 0.1;
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Limits = [1e-07 1];
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Value = 1e-07;
            app.SigmaSquaredSliderLabel = uilabel(app.UIFigure);
            app.SigmaSquaredSliderLabel.Text = 'Sigma Squared';
            app.SigmaSquaredSlider = uislider(app.UIFigure);
            app.SigmaSquaredSlider.Limits = [1e-05 5];
            app.SigmaSquaredSlider.MajorTicks = [];
            app.SigmaSquaredSlider.MajorTickLabels = {};
            app.SigmaSquaredSlider.ValueChangedFcn = createCallbackFcn(app, @SigmaSquaredSliderValueChanged, true);
            app.SigmaSquaredSlider.MinorTicks = [];
            app.SigmaSquaredSlider.Value = 0.1;
            app.EpsilonSliderLabel = uilabel(app.UIFigure);
            app.EpsilonSliderLabel.Text = 'Epsilon';
            app.EpsilonSlider = uislider(app.UIFigure);
            app.EpsilonSlider.Limits = [1e-07 1];
            app.EpsilonSlider.MajorTicks = [];
            app.EpsilonSlider.MajorTickLabels = {};
            app.EpsilonSlider.ValueChangedFcn = createCallbackFcn(app, @EpsilonSliderValueChanged, true);
            app.EpsilonSlider.MinorTicks = [];
            app.EpsilonSlider.Value = 1e-07;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EquivocationButton = uitogglebutton(app.ButtonGroup);
            app.EquivocationButton.Text = 'Equivocation';
            app.EquivocationButton.Value = true;
            app.MutualInformationButton = uitogglebutton(app.ButtonGroup);
            app.MutualInformationButton.Text = 'Mutual Information';
            app.BitsButton = uitogglebutton(app.ButtonGroup);
            app.BitsButton.Text = 'Bits';
            app.PlotCombinedButton = uibutton(app.UIFigure, 'state');
            app.PlotCombinedButton.ValueChangedFcn = createCallbackFcn(app, @PlotCombinedButtonValueChanged, true);
            app.PlotCombinedButton.Text = 'Plot Combined';
            app.ToggleRotateSwitchLabel = uilabel(app.UIFigure);
            app.ToggleRotateSwitchLabel.Text = 'Toggle Rotate';
            app.ToggleRotateSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ToggleRotateSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleRotateSwitchValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Bitwise Quasars';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Bitwise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end