classdef QPSK_capacity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        NoiseEditFieldLabel          matlab.ui.control.Label
        NoiseEditField               matlab.ui.control.NumericEditField
        HarrisonMaxEditFieldLabel    matlab.ui.control.Label
        HarrisonMaxEditField         matlab.ui.control.NumericEditField
        SmalleyMaxEditFieldLabel     matlab.ui.control.Label
        SmalleyMaxEditField          matlab.ui.control.NumericEditField
        ConferenceMaxEditFieldLabel  matlab.ui.control.Label
        ConferenceMaxEditField       matlab.ui.control.NumericEditField
        CamachoMaxEditField_2Label   matlab.ui.control.Label
        CamachoMaxEditField          matlab.ui.control.NumericEditField
        ChambersMaxEditFieldLabel    matlab.ui.control.Label
        ChambersMaxEditField         matlab.ui.control.NumericEditField
        HallwayMaxEditFieldLabel     matlab.ui.control.Label
        HallwayMaxEditField          matlab.ui.control.NumericEditField
        HarrisonMinEditFieldLabel    matlab.ui.control.Label
        HarrisonMinEditField         matlab.ui.control.NumericEditField
        SmalleyMinEditFieldLabel     matlab.ui.control.Label
        SmalleyMinEditField          matlab.ui.control.NumericEditField
        ConferenceMinEditFieldLabel  matlab.ui.control.Label
        ConferenceMinEditField       matlab.ui.control.NumericEditField
        CamachoMinEditFieldLabel     matlab.ui.control.Label
        CamachoMinEditField          matlab.ui.control.NumericEditField
        ChambersMinEditFieldLabel    matlab.ui.control.Label
        ChambersMinEditField         matlab.ui.control.NumericEditField
        HallwayMinEditFieldLabel     matlab.ui.control.Label
        HallwayMinEditField          matlab.ui.control.NumericEditField
        Slider                       matlab.ui.control.Slider
    end


    properties (Access = public)
        noise;
        h_max;
        signal;
        capacity;
    end

    methods (Access = public)
    
        function results = plotCapacity(app)
            load linear_signal.mat;
            app.signal = linear_signal;
            cpacity = (1/2) * log2(1 + (app.signal ./ app.noise));

            for y = 1:90
                for z = 1:345
                    app.capacity(y,z) = sum(cpacity(:,y,z));
                end
            end
            app.h_max = max(max(app.capacity(36:65,65:98)));
            
            imagesc(app.UIAxes, app.capacity);
            colormap(app.UIAxes, jet);
            
            app.HarrisonMaxEditField.Value = app.h_max;
            app.SmalleyMaxEditField.Value = max(max(app.capacity(34:65,123:148)));
            app.ConferenceMaxEditField.Value = max(max(app.capacity(5:65,1:61)));
            app.CamachoMaxEditField.Value = max(max(app.capacity(34:65,154:179)));
            app.ChambersMaxEditField.Value = max(max(app.capacity(34:65,213:240)));
            app.HallwayMaxEditField.Value = max(max(app.capacity(69:90,43:342)));
        end
        
        function results = plotSecrecyCapacity(app)
            for x = 1:90
                for y = 1:345
                    secrecy_capacity(x,y) = app.h_max - app.capacity(x,y);
                    if (secrecy_capacity(x,y) < 0)
                        secrecy_capacity(x,y) = 0;
                    end
                end
            end
            imagesc(app.UIAxes2, secrecy_capacity);
            colormap(app.UIAxes2, jet);
            
            app.HarrisonMinEditField.Value = min(min(secrecy_capacity(36:65,65:98)));
            app.SmalleyMinEditField.Value = min(min(secrecy_capacity(34:65,123:148)));
            app.ConferenceMinEditField.Value = min(min(secrecy_capacity(5:65,1:61)));
            app.CamachoMinEditField.Value = min(min(secrecy_capacity(34:65,154:179)));
            app.ChambersMinEditField.Value = min(min(secrecy_capacity(34:65,213:240)));
            app.HallwayMinEditField.Value = min(min(secrecy_capacity(69:90,43:342)));
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load linear_noisefloor.mat;
            app.noise = linear_noisefloor;
            plotCapacity(app);
            plotSecrecyCapacity(app);
        end

        % Value changed function: NoiseEditField
        function updateNoise(app, event)
            value = app.NoiseEditField.Value;
            load linear_noisefloor.mat;
            app.noise = linear_noisefloor .* value;
            app.Slider.Value = value;
            plotCapacity(app);
            plotSecrecyCapacity(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            load linear_noisefloor.mat;
            app.noise = linear_noisefloor .* value;
            app.NoiseEditField.Value = value;
            plotCapacity(app);
            plotSecrecyCapacity(app);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Capacity')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Secrecy Capacity')
            app.NoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseEditField.Limits = [1 1000];
            app.NoiseEditField.ValueChangedFcn = createCallbackFcn(app, @updateNoise, true);
            app.NoiseEditField.Value = 1;
            app.HarrisonMaxEditFieldLabel = uilabel(app.UIFigure);
            app.HarrisonMaxEditFieldLabel.Text = 'Harrison Max';
            app.HarrisonMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HarrisonMaxEditField.ValueDisplayFormat = '%.4f';
            app.HarrisonMaxEditField.Editable = 'off';
            app.SmalleyMaxEditFieldLabel = uilabel(app.UIFigure);
            app.SmalleyMaxEditFieldLabel.Text = 'Smalley Max';
            app.SmalleyMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmalleyMaxEditField.ValueDisplayFormat = '%.4f';
            app.SmalleyMaxEditField.Editable = 'off';
            app.ConferenceMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ConferenceMaxEditFieldLabel.Text = 'Conference Max';
            app.ConferenceMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConferenceMaxEditField.ValueDisplayFormat = '%.4f';
            app.ConferenceMaxEditField.Editable = 'off';
            app.CamachoMaxEditField_2Label = uilabel(app.UIFigure);
            app.CamachoMaxEditField_2Label.Text = 'Camacho Max';
            app.CamachoMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CamachoMaxEditField.ValueDisplayFormat = '%.4f';
            app.CamachoMaxEditField.Editable = 'off';
            app.ChambersMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ChambersMaxEditFieldLabel.Text = 'Chambers Max';
            app.ChambersMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChambersMaxEditField.ValueDisplayFormat = '%.4f';
            app.ChambersMaxEditField.Editable = 'off';
            app.HallwayMaxEditFieldLabel = uilabel(app.UIFigure);
            app.HallwayMaxEditFieldLabel.Text = 'Hallway Max';
            app.HallwayMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HallwayMaxEditField.ValueDisplayFormat = '%.4f';
            app.HallwayMaxEditField.Editable = 'off';
            app.HarrisonMinEditFieldLabel = uilabel(app.UIFigure);
            app.HarrisonMinEditFieldLabel.Text = 'Harrison Min';
            app.HarrisonMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.HarrisonMinEditField.ValueDisplayFormat = '%.4f';
            app.HarrisonMinEditField.Editable = 'off';
            app.SmalleyMinEditFieldLabel = uilabel(app.UIFigure);
            app.SmalleyMinEditFieldLabel.Text = 'Smalley Min';
            app.SmalleyMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmalleyMinEditField.ValueDisplayFormat = '%.4f';
            app.SmalleyMinEditField.Editable = 'off';
            app.ConferenceMinEditFieldLabel = uilabel(app.UIFigure);
            app.ConferenceMinEditFieldLabel.Text = 'Conference Min';
            app.ConferenceMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConferenceMinEditField.ValueDisplayFormat = '%.4f';
            app.ConferenceMinEditField.Editable = 'off';
            app.CamachoMinEditFieldLabel = uilabel(app.UIFigure);
            app.CamachoMinEditFieldLabel.Text = 'Camacho Min';
            app.CamachoMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.CamachoMinEditField.ValueDisplayFormat = '%.4f';
            app.CamachoMinEditField.Editable = 'off';
            app.ChambersMinEditFieldLabel = uilabel(app.UIFigure);
            app.ChambersMinEditFieldLabel.Text = 'Chambers Min';
            app.ChambersMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChambersMinEditField.ValueDisplayFormat = '%.4f';
            app.ChambersMinEditField.Editable = 'off';
            app.HallwayMinEditFieldLabel = uilabel(app.UIFigure);
            app.HallwayMinEditFieldLabel.Text = 'Hallway Min';
            app.HallwayMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.HallwayMinEditField.ValueDisplayFormat = '%.4f';
            app.HallwayMinEditField.Editable = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = QPSK_capacity

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end