classdef EquivocationQuasarsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ButtonGroup               matlab.ui.container.ButtonGroup
        EquivocationButton        matlab.ui.control.ToggleButton
        MutualInformationButton   matlab.ui.control.ToggleButton
        HiddenBitsButton          matlab.ui.control.ToggleButton
        EpsilonEditField          matlab.ui.control.NumericEditField
        NoiseVarianceEditField    matlab.ui.control.NumericEditField
        EpsilonSliderLabel        matlab.ui.control.Label
        EpsilonSlider             matlab.ui.control.Slider
        NoiseVarianceSliderLabel  matlab.ui.control.Label
        NoiseVarianceSlider       matlab.ui.control.Slider
        RotateSwitchLabel         matlab.ui.control.Label
        RotateSwitch              matlab.ui.control.ToggleSwitch
        TextArea                  matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
        function results = plotEquivocation(app)
            
            %%%%% EQUIVOCATION QUASARS %%%%%
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            sigmaSquared = app.NoiseVarianceSlider.Value;
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            %%%%% Plot Equivocation
            %hold on
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            surface(app.UIAxes, real_x, imag_x, H);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'H(X|z)');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            % hold off
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function results = plotMutualInformation(app)
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            sigmaSquared = app.NoiseVarianceSlider.Value;
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            I = 2 - H;
            surface(app.UIAxes, real_x, imag_x, I);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'I(Z^n;X^n)');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function plotHiddenBits(app)
            
            %%%%% EQUIVOCATION QUASARS %%%%%
            
            % Creates the QPSK symbol locations
            a = [1+j -1+j -1-j 1-j];
            
            epsilon = app.EpsilonSlider.Value;
            sigmaSquared = app.NoiseVarianceSlider.Value;
            
            for re = -50:50         % -50:50 creates a 101 x 101 grid
                for im = -50:50
                    x(re+51,im+51) = (re/25) + (im/25)*j;
                end
            end
            real_x = real(x);
            imag_x = imag(x);
            
            fx = zeros(4, 101, 101);
            fxSum = zeros(1, 101, 101);
            for loop = 1:4
                fx(loop,:,:) = (1/(2*pi*sigmaSquared))*exp((-1/(2*sigmaSquared))*abs((x-a(loop)).^2));
                fxSum(1, :,:) = fxSum(1, :,:) + fx(loop,:,:);
            end
            
            p_a = zeros(4, 101, 101);
            for loop = 1:4
                p_a(loop,:,:) = fx(loop,:,:) ./ fxSum;
            end
            
            H = zeros(101, 101);
            % probOfX = 1 / (101*101);
            sumOfProb = zeros(101, 101);
            for xCoord = -50:50
                for yCoord = -50:50
                    for loop = 1:4
                        sumOfProb(xCoord+51,yCoord+51) = sumOfProb(xCoord+51,yCoord+51) + p_a(loop,xCoord+51,yCoord+51)*log2(p_a(loop,xCoord+51,yCoord+51));
                    end
                    H((xCoord+51), (yCoord+51)) = -(sumOfProb(xCoord+51, yCoord+51));
                end
            end
            
            bits = zeros(101,101);
            I = 2 - H;
            
            for c = 1:101
                for d = 1:101
                    if (I(c,d) < epsilon)
                        bits(c,d) = 2;
                    else if (I(c,d) < 1+epsilon)
                            bits(c,d) = 1;
                        else
                            bits (c,d) = 0;
                        end
                    end
                end
            end
            
            %%%%% Bits Hidden
            
            plot(app.UIAxes, a,'o', 'MarkerFaceColor', 'white', 'Color', 'black'); % Need to fix where this plots on the z axis
            surface(app.UIAxes, real_x, imag_x, bits);
            title(app.UIAxes, "\sigma^2 = " + string(sigmaSquared));
            xlabel(app.UIAxes, 'In-Phase');
            ylabel(app.UIAxes, 'Quadrature');
            zlabel(app.UIAxes, 'Bits');
            view(app.UIAxes, 45, 60);
            colormap(app.UIAxes, jet);
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotEquivocation(app);
        end

        % Value changed function: NoiseVarianceSlider
        function NoiseVarianceSliderValueChanged(app, event)
            app.NoiseVarianceEditField.Value = app.NoiseVarianceSlider.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch (selectedButton)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changed function: EpsilonSlider
        function EpsilonSliderValueChanged(app, event)
            app.EpsilonEditField.Value = app.EpsilonSlider.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            app.EpsilonSlider.Value = app.EpsilonEditField.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
        end

        % Value changed function: NoiseVarianceEditField
        function NoiseVarianceEditFieldValueChanged(app, event)
            app.NoiseVarianceSlider.Value = app.NoiseVarianceEditField.Value;
            switch (app.ButtonGroup.SelectedObject)
                case app.EquivocationButton
                    plotEquivocation(app);
                case app.MutualInformationButton
                    plotMutualInformation(app);
                case app.HiddenBitsButton
                    plotHiddenBits(app);
            end
            
        end

        % Callback function
        function RotateButtonPushed(app, event)
            
        end

        % Value changed function: RotateSwitch
        function RotateSwitchValueChanged(app, event)
            value = app.RotateSwitch.Value;            
            if (value(1:2) == 'On')
                rotate3d(app.UIAxes,'on');
            else
                view(app.UIAxes, 45, 60);
                rotate3d(app.UIAxes,'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EquivocationButton = uitogglebutton(app.ButtonGroup);
            app.EquivocationButton.Text = 'Equivocation';
            app.EquivocationButton.Value = true;
            app.MutualInformationButton = uitogglebutton(app.ButtonGroup);
            app.MutualInformationButton.Text = 'Mutual Information';
            app.HiddenBitsButton = uitogglebutton(app.ButtonGroup);
            app.HiddenBitsButton.Text = 'Hidden Bits';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Value = 0.001;
            app.NoiseVarianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoiseVarianceEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseVarianceEditFieldValueChanged, true);
            app.NoiseVarianceEditField.Value = 0.1;
            app.EpsilonSliderLabel = uilabel(app.UIFigure);
            app.EpsilonSliderLabel.Text = 'Epsilon';
            app.EpsilonSlider = uislider(app.UIFigure);
            app.EpsilonSlider.Limits = [0 1];
            app.EpsilonSlider.ValueChangedFcn = createCallbackFcn(app, @EpsilonSliderValueChanged, true);
            app.NoiseVarianceSliderLabel = uilabel(app.UIFigure);
            app.NoiseVarianceSliderLabel.Text = 'Noise Variance';
            app.NoiseVarianceSlider = uislider(app.UIFigure);
            app.NoiseVarianceSlider.Limits = [0.01 2];
            app.NoiseVarianceSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseVarianceSliderValueChanged, true);
            app.NoiseVarianceSlider.Value = 0.1;
            app.RotateSwitchLabel = uilabel(app.UIFigure);
            app.RotateSwitchLabel.Text = 'Rotate';
            app.RotateSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RotateSwitch.ValueChangedFcn = createCallbackFcn(app, @RotateSwitchValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Quasars'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = EquivocationQuasarsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end